classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Btn_1                   matlab.ui.control.Button
        Btn_4                   matlab.ui.control.Button
        Btn_7                   matlab.ui.control.Button
        Btn_2                   matlab.ui.control.Button
        Btn_5                   matlab.ui.control.Button
        Btn_8                   matlab.ui.control.Button
        Btn_3                   matlab.ui.control.Button
        Btn_6                   matlab.ui.control.Button
        Btn_9                   matlab.ui.control.Button
        Btn_0                   matlab.ui.control.Button
        Btn_submit              matlab.ui.control.Button
        Btn_delete              matlab.ui.control.Button
        MarcacinEditFieldLabel  matlab.ui.control.Label
        Text_Tonos              matlab.ui.control.EditField
    end


    properties (Access = public)
        numeroMarcado = '' % Description
    end


    methods (Access = private)

        % Button pushed function: Btn_1
        function Btn_1Pushed(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_1.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_2
        function Boton2(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_2.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_3
        function Boton3(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_3.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_4
        function Boton4(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_4.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_5
        function Boton5(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_5.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_6
        function Boton6(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_6.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_7
        function Boton7(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_7.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_8
        function Boton8(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_8.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_9
        function Boton9(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_9.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_0
        function Boton0(app, event)
            NuevoVal = strcat(app.numeroMarcado,app.Btn_0.Text);
            app.numeroMarcado = NuevoVal;
            app.Text_Tonos.Value = app.numeroMarcado;
            disp(NuevoVal);
        end

        % Button pushed function: Btn_submit
        function Enviar(app, event)
            disp("Enviando");
            tonos_in = app.numeroMarcado;
            Fs=1600;
            tiempo_tono = 0.25;
            tiempo_no_tono = tiempo_tono;
            %tonos=cellstr(tonos_in')';%separacion de caracteres

            t = 0 : 1/Fs : tiempo_tono;

            freq_row = [697 770 852 941];
            freq_column = [1209 1336 1477];

            espacio_silencio=[1:tiempo_no_tono*Fs]*0;
            audio_marcado=[espacio_silencio];
            spec = []
            
            for m = 1:length(tonos_in) 
                switch tonos_in(m)
                    case '1', k = 1; j = 1;invalido=0;
                    case '2', k = 1; j = 2;invalido=0;
                    case '3', k = 1; j = 3;invalido=0;
                    case '4', k = 2; j = 1;invalido=0;
                    case '5', k = 2; j = 2;invalido=0;
                    case '6', k = 2; j = 3;invalido=0;
                    case '7', k = 3; j = 1;invalido=0;
                    case '8', k = 3; j = 2;invalido=0;
                    case '9', k = 3; j = 3;invalido=0;
                    case '*', k = 4; j = 1;invalido=0;
                    case '0', k = 4; j = 2;invalido=0;
                    case '#', k = 4; j = 3;invalido=0;
                    case '-', 
                        %disp('tecla -')
                        invalido=1;
                    case ' ',
                        %disp('espacio en blanco')
                        invalido=1;
                    otherwise,
                        disp('Error en la lectura de los números.')
                        break
                end
    
                if invalido == 0
                    y1 = sin(2*pi*freq_row(k)*t);
                    y2 = sin(2*pi*freq_column(j)*t);
                    y = (y1 + y2)/2;
                    audio_marcado = [audio_marcado y espacio_silencio];
                    spec = [spec y1 y2];
                end
            end
            
            time_for_plot = (0:length( audio_marcado)-1)/Fs;
            
            figure;
            plot(time_for_plot, audio_marcado, 'k');
            title('Señal periódica formada a partir de la suma de sinusoidales', 'FontSize',14);
            ylabel('Amplitud');
            xlabel('Tiempo (seg)');
            ylim([-1.5,1.5]);
            grid on;
            
            n = length(audio_marcado);
            Y = fft(audio_marcado)/n;
            Mag_Y = abs(Y);
            f = (0 : n-1) * (Fs/n);
            disp("=================================================================");
            disp(spec);
            
            figure;
            bar(f, Mag_Y);
            xlabel('Frecuencia (Hz)');
            ylabel('Magnitud');
            title('Espectro en magnitud de la señal periódica formada a partir de la suma de sinusoidales');
            grid on;
            
        end

        % Button pushed function: Btn_delete
        function Borrar(app, event)
            app.numeroMarcado = '';
            disp(app.numeroMarcado);
            app.Text_Tonos.Value = app.numeroMarcado;
        end

        % Value changing function: Text_Tonos
        function AsignarValor(app, event)
            app.numeroMarcado = event.Value;
            disp(event.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Btn_1 = uibutton(app.UIFigure, 'push');
            app.Btn_1.ButtonPushedFcn = createCallbackFcn(app, @Btn_1Pushed, true);
            app.Btn_1.Text = '1';
            app.Btn_4 = uibutton(app.UIFigure, 'push');
            app.Btn_4.ButtonPushedFcn = createCallbackFcn(app, @Boton4, true);
            app.Btn_4.Text = '4';
            app.Btn_7 = uibutton(app.UIFigure, 'push');
            app.Btn_7.ButtonPushedFcn = createCallbackFcn(app, @Boton7, true);
            app.Btn_7.Text = '7';
            app.Btn_2 = uibutton(app.UIFigure, 'push');
            app.Btn_2.ButtonPushedFcn = createCallbackFcn(app, @Boton2, true);
            app.Btn_2.Text = '2';
            app.Btn_5 = uibutton(app.UIFigure, 'push');
            app.Btn_5.ButtonPushedFcn = createCallbackFcn(app, @Boton5, true);
            app.Btn_5.Text = '5';
            app.Btn_8 = uibutton(app.UIFigure, 'push');
            app.Btn_8.ButtonPushedFcn = createCallbackFcn(app, @Boton8, true);
            app.Btn_8.Text = '8';
            app.Btn_3 = uibutton(app.UIFigure, 'push');
            app.Btn_3.ButtonPushedFcn = createCallbackFcn(app, @Boton3, true);
            app.Btn_3.Text = '3';
            app.Btn_6 = uibutton(app.UIFigure, 'push');
            app.Btn_6.ButtonPushedFcn = createCallbackFcn(app, @Boton6, true);
            app.Btn_6.Text = '6';
            app.Btn_9 = uibutton(app.UIFigure, 'push');
            app.Btn_9.ButtonPushedFcn = createCallbackFcn(app, @Boton9, true);
            app.Btn_9.Text = '9';
            app.Btn_0 = uibutton(app.UIFigure, 'push');
            app.Btn_0.ButtonPushedFcn = createCallbackFcn(app, @Boton0, true);
            app.Btn_0.Text = '0';
            app.Btn_submit = uibutton(app.UIFigure, 'push');
            app.Btn_submit.ButtonPushedFcn = createCallbackFcn(app, @Enviar, true);
            app.Btn_submit.Icon = 'send-button.png';
            app.Btn_submit.Text = '';
            app.Btn_delete = uibutton(app.UIFigure, 'push');
            app.Btn_delete.ButtonPushedFcn = createCallbackFcn(app, @Borrar, true);
            app.Btn_delete.Icon = 'backspace-arrow.png';
            app.Btn_delete.Text = '';
            app.MarcacinEditFieldLabel = uilabel(app.UIFigure);
            app.MarcacinEditFieldLabel.Text = 'Marcación';
            app.Text_Tonos = uieditfield(app.UIFigure, 'text');
            app.Text_Tonos.ValueChangingFcn = createCallbackFcn(app, @AsignarValor, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end